classdef imgRegMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        Start                       matlab.ui.container.toolbar.PushTool
        Load                        matlab.ui.container.toolbar.PushTool
        Save                        matlab.ui.container.toolbar.PushTool
        GridLayout9                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        RemoveSelectedRowsButton    matlab.ui.control.Button
        RegisterImagesButton        matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        LoadFolderButton            matlab.ui.control.Button
        imageVersionDropDown        matlab.ui.control.DropDown
        data                        matlab.ui.control.Table
        ConfigTab                   matlab.ui.container.Tab
        GridLayout8                 matlab.ui.container.GridLayout
        Tree                        matlab.ui.container.Tree
        OptimiserNode               matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Node4                       matlab.ui.container.TreeNode
        MetricNode                  matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        DataNode                    matlab.ui.container.TreeNode
        Node_3                      matlab.ui.container.TreeNode
        ImageTab                    matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout12                matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        GridLayout11                matlab.ui.container.GridLayout
        SelectionAmountButtonGroup  matlab.ui.container.ButtonGroup
        Tree2                       matlab.ui.container.CheckBoxTree
        Node_4                      matlab.ui.container.TreeNode
        Node4_2                     matlab.ui.container.TreeNode
        UseallCheckBox              matlab.ui.control.CheckBox
        Spinner                     matlab.ui.control.Spinner
        RecenterButton              matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        RemoveRowsMenu              matlab.ui.container.Menu
    end


    properties (Access = private)
        config struct %struct representing the config file
        process_load_data_table table %table needed to process additional functions
        previousPivotIndex int16 = 0 %index for changing pivot
        hidden_data table %hidden table with columns 
        colName = dictionary("select", 'Select', "pivot", 'Pivot', "name", 'Name', "path", 'Path') %column names for visible data
        hColName = dictionary("tform", 'tForm') %column names for hidden data
        
    end
    
    methods (Access = private)
        
        function initWorkingDir(app)

            excludedPaths = {'.git', 'docs'};
            currentDir = pwd;
            
            excludePattern = cellfun(@(str) fullfile(currentDir, str), excludedPaths, 'UniformOutput', false);
            
            
            allPaths = genpath(currentDir);
            allPaths = strsplit(allPaths, pathsep);
            allPaths = allPaths(~cellfun('isempty', allPaths));
            keepPaths = true(size(allPaths));
            
            for i = 1: length(excludePattern)
                folder = excludePattern{i};
                keepPaths = keepPaths & ~startsWith(allPaths, folder);
            end
            
            filteredPaths = allPaths(keepPaths);
            
            for i = 1: length(filteredPaths)
                addpath(filteredPaths{i});
            end
            
            disp('Paths added to MATLAB:');
            disp(filteredPaths);
        end

        function appendHiddenData(app, rowsToAdd)
            idx = strcmp(app.hColName("tform"), app.hidden_data.Properties.VariableNames);
            toInit = app.hidden_data.Properties.VariableNames(~idx);
            defTForm = affinetform2d();
            curHeight = height(app.hidden_data);

            for j=1:rowsToAdd
                new_row = cell(1, length(toInit)+1);
                new_row{1} = defTForm;
                rIdx = j + curHeight;

                for i=1:length(toInit)
                    new_row{i+1} = affineOutputView(size(app.data.Data.(toInit{i}){rIdx}),defTForm,"BoundsStyle","FollowOutput");
                end
                app.hidden_data = [app.hidden_data; new_row];
            end
        end
   

        function appendLoadData(app, tempTable)
            tempTable.(app.colName("select")) = true(height(tempTable),1);
            tempTable.(app.colName("pivot")) = false(height(tempTable),1);
            
            toInit = setdiff(app.data.ColumnName, tempTable.Properties.VariableNames);
            for i=1:length(toInit)
                tempTable.(toInit{i}) = {};
            end

            reorderedTable = tempTable(:, app.data.ColumnName);
            mergedData = [app.data.Data; reorderedTable];
            [~, uniqueIdx] = unique(mergedData.Name, 'stable');
            
            rowsToAdd = length(uniqueIdx) - height(app.data.Data);
            app.data.Data = mergedData(uniqueIdx, :);
            appendHiddenData(app, rowsToAdd);
        end

        function tempTable = processLoadData(app, filenames, filepaths)
            tempTable = table(filenames, filepaths);
            tempTable.Properties.VariableNames = [app.colName("name"),app.colName("path")];    
            
            for i=1:height(app.process_load_data_table)

                columns = cell(1, length(app.process_load_data_table.input_cols{i}));
                for j=1:length(columns)
                    columns{j} = tempTable.(app.process_load_data_table.input_cols{i}{j});
                end
                handle = app.process_load_data_table.handles{i};
                tempTable.(app.process_load_data_table.output_cols{i}) = handle(columns{:});
            end
        end
        
        function addColumn(app, col_name, editable, col_width)
            arguments (Input)
                app 
                col_name char
                editable logical = false
                col_width = 'auto'
            end
            if isequal(length(app.data.ColumnName), 0)
                app.data.ColumnName = {col_name};
                app.data.ColumnEditable = [editable];
                app.data.ColumnWidth = {col_width};
            else
                app.data.ColumnName = [app.data.ColumnName', {col_name}]';
                app.data.ColumnEditable = [app.data.ColumnEditable, editable];
                app.data.ColumnWidth = [app.data.ColumnWidth, {col_width}];
            end
        end

        function col_name = ProcessConfigColumn(app, column_string)
            tokens = regexp(column_string, '(\w+)\s*=\s*(\w+)\(([\w, ]*)\)', 'tokens');% Extract components from prompt

            tokens = tokens{1}; % Extract inner cell array
            col_name = tokens{1};      % column label
            func_to_run = tokens{2};    % function to run
            func_inputs = strtrim(tokens{3});  %function inputs
            func_inputs_arr = strtrim(strsplit(func_inputs, ','));
            % Construct the function string for str2func
            func_str = sprintf("@(%s) %s(%s)", func_inputs, func_to_run, func_inputs);
            
            new_row = {col_name, {func_str}, {func_inputs_arr}'};
            app.process_load_data_table = [app.process_load_data_table; new_row];
        end
        
        function updateImageVdropdown(app, name)
            %{
            if strcmp(app.imageVersionDropDown.Items{1}, "no image function given")
                app.imageVersionDropDown.Items = {name};
            else 
            
            end
            %}
            app.imageVersionDropDown.Items = [app.imageVersionDropDown.Items, {name}];
            
        end

        function initData(app)
            
            %INIT VISIBLE TABLE
            ogCols = [app.colName("pivot"), app.colName("select"), app.colName("name"), app.colName("path")];
            ogWidth = [60, 60, 75, 75];
            ogEditable = [true, true, false, false];
            for i=1:length(ogCols)
                addColumn(app, ogCols(i), ogEditable(i), ogWidth(i));
            end
            
            %INIT EXTRA COLUMN FUNCTIONS TABLE
            colNames = {'output_cols', 'handles', 'input_cols'};
            varTypes = {'cell', 'cell', 'cell'};
            app.process_load_data_table = table('Size',[0,length(colNames)],'VariableTypes',varTypes, 'VariableNames',colNames);
            totalLen = length(app.config.data.ExtraColumns) + length(app.config.data.FetchDataCols);
            cols = strings(totalLen, 1);
            
             %INIT HIDDEN TABLE
            colNames = app.hColName('tform');
            varTypes = {'affinetform2d'};
            app.hidden_data = table('Size',[0,length(colNames)],'VariableTypes',varTypes, 'VariableNames',colNames);


            %PROCESS FUNCTIONS TO ADD COLUMNS
            for i=1:length(app.config.data.ExtraColumns)
                ProcessConfigColumn(app, app.config.data.ExtraColumns{i});
            end

            for i=1:length(app.config.data.FetchDataCols)
                col_name = ProcessConfigColumn(app, app.config.data.FetchDataCols{i});
                updateImageVdropdown(app, col_name);
                if ~strcmp(col_name, app.hColName("tform"))
                    app.hidden_data.(col_name) = cell(0,1);
                else
                    error("added column %s has same name as internal column %s, please change either of these values", col_name, app.hColName("tform"));
                end
            end

            %RESOLVE AND ADD EXTRA FUNCTIONS TO TABLE
            app.process_load_data_table = ResolveProcessTableOrder(app, ogCols, app.process_load_data_table);
        end
        
        function newTable = ResolveProcessTableOrder(app, existCol, oldTable)
            newTable = oldTable;
            newTable(:, :) = [];

            while height(oldTable) > 0
                noResolution = true;
                for i=1:height(oldTable)
                    if all(ismember(oldTable.input_cols{i}, existCol))
                        noResolution = false;
                        %add check for duplicates
                        if ~checkDuplicates(i)
                            newTable = [newTable; oldTable(i, :)];
                            addColumn(app, oldTable.output_cols{i});
                            existCol = [existCol'; oldTable.output_cols{i}]';
                        end
                        oldTable(i, :) = [];
                        break;
                    end
                end
                if noResolution
                    error("Can't resolve added columns. Check for circular inputs in the function inputs")
                end
            end
           
            for i=1:height(newTable)
                newTable.handles(i) = {str2func(newTable.handles{i})};
            end
            
            function bool = checkDuplicates(row)
                bool = false;
                if isequal(height(newTable), 0)
                    return
                elseif ismember(oldTable.handles{row}, string(newTable.handles))
                    bool = true;
                    return
                elseif ismember(oldTable.output_cols{row}, string(newTable.output_cols))
                    error("Column names must be different, please remove duplicated: %s", oldTable.output_cols{row});
                end
            end
        end
        
        function updateTForms(app, tforms, selectIDx, label)
            if ~(length(tforms) == length(selectIDx))
                error("BUG: length of selected images does not equal length of returned registered transforms")
            end

            for i=1:length(selectIDx)
                app.hidden_data.(app.hColName("tform"))(selectIDx(i)) = tforms(i);
                app.hidden_data.(label)(selectIDx(i)) = affineOutputView(size(app.data.Data.(label){selectIDx(i)}),tforms(i),"BoundsStyle","FollowOutput");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initWorkingDir();
            
            defaultConfigStr = "default_config.mat";
            loadedStruct = load(fullfile("config",defaultConfigStr), "config");
            app.config = loadedStruct.config;
            

            if strcmp(app.config.name, defaultConfigStr)
                app.config = load(fullfile("config", app.config.name), "config");
            end
            app.initData();
            
        end

        % Callback function: Load, LoadFolderButton
        function LoadDataDir(app, event)
            [filenames, filepaths] = fetchDir();
            if ~ isequal(length(filenames), 0)
                pathsTable = processLoadData(app, filenames, filepaths);
                appendLoadData(app, pathsTable);
            end
        end

        % Callback function: RemoveRowsMenu, RemoveSelectedRowsButton
        function removeSelectedRows(app, event)
            if ~ strcmp(app.data.SelectionType, 'row')
                error("Selection type is not row");
            end
            selRows = app.data.Selection;
            app.data.Selection = [];
            app.data.Data(selRows, :) = [];
            app.hidden_data(selRows, :) = [];
            
        end

        % Button pushed function: LoadFileButton
        function LoadDataFile(app, event)
            [filenames, filepaths] = fetchFile();
            if ~ isequal(length(filenames), 0)
                pathsTable = processLoadData(app, filenames, filepaths);
                appendLoadData(app, pathsTable);
            end
        end

        % Callback function: Start
        function runRegistration(app, event)

           %get info on selected images, and image type
           imgLabel = app.imageVersionDropDown.Value;
           selectID = app.data.Data.(app.colName("select"));
           pivotID = app.data.Data.(app.colName("pivot"));
           selectedImgs = app.data.Data.(imgLabel)(selectID);
           pivot = app.data.Data.(imgLabel)(pivotID);
           if isempty(pivot)
               pivot = {0};
           end
            
           %run optimisation
           transforms = registerImgs(selectedImgs, pivot);
           %tranforms(1) is a blank affinetform2d, unchanged for the pivot

           %populate the table with the transforms
           idx = find(selectID);
           updateTForms(app, transforms(2:end), idx, imgLabel)
        end

        % Cell edit callback: data
        function fixPivot(app, event)
            select = app.colName("select");
            pivot = app.colName("pivot");
            row = event.Indices(1);
            col = event.Indices(2);

            if app.data.ColumnName(col) == pivot
                prevRow = app.previousPivotIndex;
                
                if event.NewData == 1
                    if ~isequal(app.previousPivotIndex, 0)
                        app.data.Data.(pivot)(prevRow) = 0;
                        app.data.Data.(select)(prevRow) = 1;
                    end
                    app.data.Data.(select)(row) = 0;
                    app.previousPivotIndex = row;
                else
                    app.data.Data.(select)(row) = 1;
                    app.previousPivotIndex = 0;
                end
                app.data.Data.(pivot)(row) = event.NewData;
            elseif app.data.ColumnName(col) == select
                if row == app.previousPivotIndex
                    app.data.Data.(select)(row) = 0;
                end
            end
        end

        % Callback function: ImageTab, RecenterButton
        function plotImages(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Start = uipushtool(app.Toolbar);
            app.Start.ClickedCallback = createCallbackFcn(app, @runRegistration, true);
            app.Start.Icon = fullfile(pathToMLAPP, 'res', 'next-button.png');
            app.Start.Separator = 'on';
            app.Load = uipushtool(app.Toolbar);
            app.Load.ClickedCallback = createCallbackFcn(app, @LoadDataDir, true);
            app.Load.Icon = fullfile(pathToMLAPP, 'res', 'upload.png');
            app.Load.Separator = 'on';
            app.Save = uipushtool(app.Toolbar);
            app.Save.Icon = fullfile(pathToMLAPP, 'res', 'save.png');
            app.Save.Separator = 'on';
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout5 = uigridlayout(app.DataTab);
            app.data = uitable(app.GridLayout5);
            app.data.ColumnName = '';
            app.data.ColumnRearrangeable = 'on';
            app.data.RowName = {};
            app.data.SelectionType = 'row';
            app.data.CellEditCallback = createCallbackFcn(app, @fixPivot, true);
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.imageVersionDropDown = uidropdown(app.GridLayout10);
            app.imageVersionDropDown.Items = {};
            app.imageVersionDropDown.Placeholder = 'no image function given';
            app.imageVersionDropDown.Value = {};
            app.LoadFolderButton = uibutton(app.GridLayout10, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataDir, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.LoadFileButton = uibutton(app.GridLayout10, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFile, true);
            app.LoadFileButton.Text = 'Load File';
            app.RegisterImagesButton = uibutton(app.GridLayout10, 'push');
            app.RegisterImagesButton.Text = {'Register'; 'Images'};
            app.RemoveSelectedRowsButton = uibutton(app.GridLayout10, 'push');
            app.RemoveSelectedRowsButton.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveSelectedRowsButton.Text = {'Remove'; 'Selected'; 'Rows'};
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.GridLayout8 = uigridlayout(app.ConfigTab);
            app.Tree = uitree(app.GridLayout8);
            app.OptimiserNode = uitreenode(app.Tree);
            app.OptimiserNode.Text = 'Optimiser';
            app.Node2 = uitreenode(app.OptimiserNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.OptimiserNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.OptimiserNode);
            app.Node4.Text = 'Node4';
            app.MetricNode = uitreenode(app.Tree);
            app.MetricNode.Text = 'Metric';
            app.Node = uitreenode(app.MetricNode);
            app.Node.Text = 'Node';
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.Node_3 = uitreenode(app.DataNode);
            app.Node_3.Text = 'Node';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageTab.ButtonDownFcn = createCallbackFcn(app, @plotImages, true);
            app.GridLayout4 = uigridlayout(app.ImageTab);
            app.GridLayout11 = uigridlayout(app.GridLayout4);
            app.RecenterButton = uibutton(app.GridLayout11, 'push');
            app.RecenterButton.ButtonPushedFcn = createCallbackFcn(app, @plotImages, true);
            app.RecenterButton.Text = 'Recenter';
            app.SelectionAmountButtonGroup = uibuttongroup(app.GridLayout11);
            app.SelectionAmountButtonGroup.Title = 'Selection Amount';
            app.Spinner = uispinner(app.SelectionAmountButtonGroup);
            app.UseallCheckBox = uicheckbox(app.SelectionAmountButtonGroup);
            app.UseallCheckBox.Text = 'Use all';
            app.Tree2 = uitree(app.SelectionAmountButtonGroup, 'checkbox');
            app.Node_4 = uitreenode(app.Tree2);
            app.Node_4.Text = 'Node';
            app.Node4_2 = uitreenode(app.Node_4);
            app.Node4_2.Text = 'Node4';
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.UIAxes = uiaxes(app.GridLayout12);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RemoveRowsMenu = uimenu(app.ContextMenu);
            app.RemoveRowsMenu.MenuSelectedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveRowsMenu.Text = 'Remove Rows';
            % Assign app.ContextMenu
            app.data.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgRegMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end